classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        XEditFieldLabel    matlab.ui.control.Label
        X1                 matlab.ui.control.NumericEditField
        YEditFieldLabel    matlab.ui.control.Label
        Y1                 matlab.ui.control.NumericEditField
        Button             matlab.ui.control.Button
        mmLabel            matlab.ui.control.Label
        Result             matlab.ui.control.NumericEditField
        ZEditFieldLabel    matlab.ui.control.Label
        Z1                 matlab.ui.control.NumericEditField
        XEditFieldLabel_2  matlab.ui.control.Label
        X2                 matlab.ui.control.NumericEditField
        YEditFieldLabel_2  matlab.ui.control.Label
        Y2                 matlab.ui.control.NumericEditField
        ZEditField_2Label  matlab.ui.control.Label
        Z2                 matlab.ui.control.NumericEditField
        mLabel             matlab.ui.control.Label
        Result_2           matlab.ui.control.NumericEditField
        Label              matlab.ui.control.Label
        Xuxu_CompileLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: X1
        function X1ValueChanged(app, event)

        end

        % Value changed function: Y1
        function Y1ValueChanged(app, event)

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
           x1re = app.X1.Value ;
           y1re = app.Y1.Value ;
           z1re = app.Z1.Value ;
           x2re = app.X2.Value ;
           y2re = app.Y2.Value ;
           z2re = app.Z2.Value ;
           app.Result.Value = sqrt((x1re - x2re).^2 +(y1re-y2re).^2+ (z2re-z1re).^2) ;
           app.Result_2.Value = app.Result.Value /1000 ;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X坐标';
            app.X1 = uieditfield(app.UIFigure, 'numeric');
            app.X1.ValueChangedFcn = createCallbackFcn(app, @X1ValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y坐标';
            app.Y1 = uieditfield(app.UIFigure, 'numeric');
            app.Y1.ValueChangedFcn = createCallbackFcn(app, @Y1ValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '计算';
            app.mmLabel = uilabel(app.UIFigure);
            app.mmLabel.Text = '结果 mm';
            app.Result = uieditfield(app.UIFigure, 'numeric');
            app.Result.ValueDisplayFormat = '%.6f';
            app.Result.Editable = 'off';
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z坐标';
            app.Z1 = uieditfield(app.UIFigure, 'numeric');
            app.XEditFieldLabel_2 = uilabel(app.UIFigure);
            app.XEditFieldLabel_2.Text = 'X坐标';
            app.X2 = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel_2 = uilabel(app.UIFigure);
            app.YEditFieldLabel_2.Text = 'Y坐标';
            app.Y2 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_2Label = uilabel(app.UIFigure);
            app.ZEditField_2Label.Text = 'Z坐标';
            app.Z2 = uieditfield(app.UIFigure, 'numeric');
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = '结果 m';
            app.Result_2 = uieditfield(app.UIFigure, 'numeric');
            app.Result_2.ValueDisplayFormat = '%.8f';
            app.Result_2.Editable = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '计算两点距离';
            app.Xuxu_CompileLabel = uilabel(app.UIFigure);
            app.Xuxu_CompileLabel.Text = 'Xuxu_Compile';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end